classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RESMSEButton     matlab.ui.control.Button
        Image            matlab.ui.control.Image
        TahminResult     matlab.ui.control.Label
        ARACSAYISILabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RESMSEButton
        function RESMSEButtonPushed(app, event)
            [filename,filepath]=uigetfile({'*.png'},'Bir resim seçin.');
            fullname = [filepath, filename];
            inputImage = imread(fullname);
            app.Image.ImageSource = fullname;
            load("mynet3out.mat");
            [output1,output2,count] = detectCarRandom(mynet,inputImage,randi(10),randi(30)+30);
            app.Image.ImageSource = output2;
            pause(2);
            app.Image.ImageSource = output1;
            app.TahminResult.Text = string(count(1));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RESMSEButton = uibutton(app.UIFigure, 'push');
            app.RESMSEButton.ButtonPushedFcn = createCallbackFcn(app, @RESMSEButtonPushed, true);
            app.RESMSEButton.Text = 'RESİM SEÇ';
            app.Image = uiimage(app.UIFigure);
            app.TahminResult = uilabel(app.UIFigure);
            app.TahminResult.Text = '-';
            app.ARACSAYISILabel = uilabel(app.UIFigure);
            app.ARACSAYISILabel.Text = 'ARAC SAYISI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end